classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANNDAforMedicalPredictionUIFigure  matlab.ui.Figure
        Image                 matlab.ui.control.Image
        TrainOptimizeMLPwithDAGWOButton  matlab.ui.control.Button
        PredictCOVID19Button  matlab.ui.control.Button
        EXITButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 app.ANNDAforMedicalPredictionUIFigure.Visible = 'off'; % hide figure while Icon is still old icon
drawnow %It seems we need to pause execution here otherwise cefWindowList won't have the UIFigure in it yet
% Find the CEF window for the running app's figure: this idea
% comes from DesktopFigureService MALTAB internal class (courtesy)
cefWindowList = matlab.internal.webwindowmanager.instance.windowList;
url = matlab.ui.internal.FigureServices.getFigureURL(app.ANNDAforMedicalPredictionUIFigure);
[idx] = arrayfun(@(ww)strcmp(url,ww.URL), cefWindowList);
hWin = cefWindowList(idx);

    hWin.Icon = which('bg.ico');

app.ANNDAforMedicalPredictionUIFigure.Visible = 'on';
        end

        % Button pushed function: TrainOptimizeMLPwithDAGWOButton
        function TrainOptimizeMLPwithDAGWOButtonPushed(app, event)
            app2;
        end

        % Button pushed function: PredictCOVID19Button
        function PredictCOVID19ButtonPushed(app, event)
            app3;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANNDAforMedicalPredictionUIFigure = uifigure('Visible', 'off');
            app.ANNDAforMedicalPredictionUIFigure.Name = 'ANN-DA for Medical Prediction';
            app.Image = uiimage(app.ANNDAforMedicalPredictionUIFigure);
            app.Image.ImageSource = 'bg2.png';
            app.TrainOptimizeMLPwithDAGWOButton = uibutton(app.ANNDAforMedicalPredictionUIFigure, 'push');
            app.TrainOptimizeMLPwithDAGWOButton.ButtonPushedFcn = createCallbackFcn(app, @TrainOptimizeMLPwithDAGWOButtonPushed, true);
            app.TrainOptimizeMLPwithDAGWOButton.Text = 'Train/Optimize MLP with DA/GWO';
            app.PredictCOVID19Button = uibutton(app.ANNDAforMedicalPredictionUIFigure, 'push');
            app.PredictCOVID19Button.ButtonPushedFcn = createCallbackFcn(app, @PredictCOVID19ButtonPushed, true);
            app.PredictCOVID19Button.Text = 'Predict COVID-19';
            app.EXITButton = uibutton(app.ANNDAforMedicalPredictionUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.ANNDAforMedicalPredictionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANNDAforMedicalPredictionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANNDAforMedicalPredictionUIFigure)
        end
    end
end