classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainOptimizeMLPwithDAGWOUIFigure  matlab.ui.Figure
        Image                        matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
        GotopredcitCOVID19appButton  matlab.ui.control.Button
        EXITButton                   matlab.ui.control.Button
        ChoosedatasetButtonGroup     matlab.ui.container.ButtonGroup
        BreastCancerButton           matlab.ui.control.RadioButton
        HepatitisButton              matlab.ui.control.RadioButton
        HeartDiseaseButton           matlab.ui.control.RadioButton
        OptimizerTrainerButtonGroup  matlab.ui.container.ButtonGroup
        DAButton                     matlab.ui.control.RadioButton
        GWOButton                    matlab.ui.control.RadioButton
        TrainButton                  matlab.ui.control.Button
        clearplotButton              matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        MSEEditFieldLabel            matlab.ui.control.Label
        MSEEditField                 matlab.ui.control.EditField
        AccuracyEditFieldLabel       matlab.ui.control.Label
        AccuracyEditField            matlab.ui.control.EditField
        TrainingtimeEditFieldLabel   matlab.ui.control.Label
        TrainingtimeEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
       
       curve ;
 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
app.TextArea.Value='';
app.AccuracyEditField.Value='';
app.MSEEditField.Value='';
app.TrainingtimeEditField.Value='';
tic;
rng('default');

% classification datasets:
 %Function_name='F1'; %MLP_Cancer dataset
 % Function_name='F2'; %MLP_Heart dataset
 %Function_name='F3'; %MLP_Hepatitis dataset
% Load details of the selected data set
%[lb,ub,dim,fobj]=Get_Functions_details(Function_name);

SearchAgents_no=20; % Number of search agents for cancer DA
SearchAgents_no1=21;% Number of search agents for cancer GWO
Max_iteration=50; % Maximum numbef of iterations for cancer mlp

%hd da 90%
 it=50; sa=20;  


%caner DA i=100 sa=40 91%
%cancer DA i=50 sa=20 90%
%cancer gwo i=50 sa=21 75%


%hd da i=100 sa=45 sa1=20 90%

%hep  91,25%
ithep=50; sahep=20;


curve=app.UIAxes;
%curve2=app.UIAxes
%curve1=app.UIAxes
%ta=app.TextArea;
%obj=app;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Cancer_DA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.BreastCancerButton.Value==1 && app.DAButton.Value==1


[lb,ub,dim,fobj]=Get_Functions_details('F1');
    
    load Cancer.txt
    x=Cancer;
    %I2=x(1:150,1:4);
    H2=x(1:699,2:11);
    for iii=1:699
        for jjj=1:10
            H2(iii,jjj)=((H2(iii,jjj)-1)/9);
        end
    end
    I2=H2(1:699,1:9);
    
    T=H2(1:699,10);
    Hno=19;
    dim=11*19;


[Best_score,Best_pos,DA_cg_curve]=DA(SearchAgents_no,Max_iteration,lb,ub,dim,fobj,curve,app);
        
        Rrate=0;
        W=Best_pos(1:10*Hno);
        B=Best_pos(10*Hno+1:dim);
       
        for pp=600:699
            actualvalue=my_simulate(9,Hno,1,W,B,I2(pp,:) );
            if(T(pp)>=0.3 && T(pp)<0.4)
                if (abs(actualvalue-0.333333333333333)<0.1)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)>=0.1 && T(pp)<0.2)
                if (abs(actualvalue-0.111111111111111)<0.1)
                    Rrate=Rrate+1;
                end
            end
            
            
            app.AccuracyEditField.Value=strcat( num2str((Rrate/100)*100),'%');
            pause(0.02);


        end


        Final_rate=(Rrate/100)*100 ;  
       
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);


end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%CancerGWO%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.BreastCancerButton.Value==1 && app.GWOButton.Value==1
    
[lb,ub,dim,fobj]=Get_Functions_details('F1');
    
    load Cancer.txt
    x=Cancer;
    %I2=x(1:150,1:4);
    H2=x(1:699,2:11);
    for iii=1:699
        for jjj=1:10
            H2(iii,jjj)=((H2(iii,jjj)-1)/9);
        end
    end
    I2=H2(1:699,1:9);
    
    T=H2(1:699,10);
    Hno=19;
    dim=11*19;


[Best_score,Best_pos,GWO_cg_curve]=GWO(SearchAgents_no1,Max_iteration,lb,ub,dim,fobj,curve,app);        
        Rrate=0;
        W=Best_pos(1:10*Hno);
        B=Best_pos(10*Hno+1:dim);
       
        for pp=600:699
            actualvalue=my_simulate(9,Hno,1,W,B,I2(pp,:) );
            if(T(pp)>=0.3 && T(pp)<0.4)
                if (abs(actualvalue-0.333333333333333)<0.1)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)>=0.1 && T(pp)<0.2)
                if (abs(actualvalue-0.111111111111111)<0.1)
                    Rrate=Rrate+1;
                end
            end
            app.AccuracyEditField.Value=strcat( num2str((Rrate/100)*100),'%');
            pause(0.02);
        end


        Final_rate=(Rrate/100)*100 ;  
       
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);


end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Heart_Disease_DA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.HeartDiseaseButton.Value==1 && app.DAButton.Value==1


[lb,ub,dim,fobj]=Get_Functions_details('F2');

 load Heart_test.txt
    x=Heart_test;
    %I2=x(1:150,1:4);
    I2(:,1)=x(1:187,2);
    I2(:,2)=x(1:187,3);
    I2(:,3)=x(1:187,4);
    I2(:,4)=x(1:187,5);
    I2(:,5)=x(1:187,6);
    I2(:,6)=x(1:187,7);
    I2(:,7)=x(1:187,8);
    I2(:,8)=x(1:187,9);
    I2(:,9)=x(1:187,10);
    I2(:,10)=x(1:187,11);
    I2(:,11)=x(1:187,12);
    I2(:,12)=x(1:187,13);
    I2(:,13)=x(1:187,14);
    I2(:,14)=x(1:187,15);
    I2(:,15)=x(1:187,16);
    I2(:,16)=x(1:187,17);
    I2(:,17)=x(1:187,18);
    I2(:,18)=x(1:187,19);
    I2(:,19)=x(1:187,20);
    I2(:,20)=x(1:187,21);
    I2(:,21)=x(1:187,22);
    I2(:,22)=x(1:187,23);
    T=x(1:187,1);
    %I=(I2-0.1)./(7.9-0.1)
    
    Hno=45;
    dim = 24*45+1


[Best_score,Best_pos]=DA(sa,it,lb,ub,dim,fobj,curve,app);


        Rrate=0;
        W=Best_pos(1:23*Hno);
        B=Best_pos(23*Hno+1:dim);
        for pp=1:187
            actualvalue=my_simulate(22,Hno,1,W,B,I2(pp,:) );
            if(T(pp)==1)
                if (actualvalue>=0.95)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)==0)
                if (actualvalue(1)<0.05)
                    Rrate=Rrate+1;
                end
            end
            app.AccuracyEditField.Value=strcat( num2str((Rrate/187)*100),'%');
            pause(0.02);
            
        end
        
        Final_rate=(Rrate/187)*100;
         
       
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);


end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Heart_Disease_GWO%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.HeartDiseaseButton.Value==1 && app.GWOButton.Value==1


[lb,ub,dim,fobj]=Get_Functions_details('F2');

 load Heart_test.txt
    x=Heart_test;
    %I2=x(1:150,1:4);
    I2(:,1)=x(1:187,2);
    I2(:,2)=x(1:187,3);
    I2(:,3)=x(1:187,4);
    I2(:,4)=x(1:187,5);
    I2(:,5)=x(1:187,6);
    I2(:,6)=x(1:187,7);
    I2(:,7)=x(1:187,8);
    I2(:,8)=x(1:187,9);
    I2(:,9)=x(1:187,10);
    I2(:,10)=x(1:187,11);
    I2(:,11)=x(1:187,12);
    I2(:,12)=x(1:187,13);
    I2(:,13)=x(1:187,14);
    I2(:,14)=x(1:187,15);
    I2(:,15)=x(1:187,16);
    I2(:,16)=x(1:187,17);
    I2(:,17)=x(1:187,18);
    I2(:,18)=x(1:187,19);
    I2(:,19)=x(1:187,20);
    I2(:,20)=x(1:187,21);
    I2(:,21)=x(1:187,22);
    I2(:,22)=x(1:187,23);
    T=x(1:187,1);
    %I=(I2-0.1)./(7.9-0.1)
    
    Hno=45;
    dim = 24*45+1


[Best_score,Best_pos]=GWO(sa,it,lb,ub,dim,fobj,curve,app);
%cla(app.UIAxes);
%[Best_score(i,:),Best_pos(i,:)]=GWO(sa1,it,lb,ub,dim,fobj,curve,app);
    
        
        Rrate=0;
        W=Best_pos(1:23*Hno);
        B=Best_pos(23*Hno+1:dim);
        for pp=1:187
            actualvalue=my_simulate(22,Hno,1,W,B,I2(pp,:) );
            if(T(pp)==1)
                if (actualvalue>=0.95)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)==0)
                if (actualvalue(1)<0.05)
                    Rrate=Rrate+1;
                end
            end
            app.AccuracyEditField.Value=strcat( num2str((Rrate/187)*100),'%');
            pause(0.02);
        end
        
        Final_rate=(Rrate/187)*100;
         
       
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);


end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Hepatitis_DA%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.HepatitisButton.Value==1 && app.DAButton.Value==1
 [lb,ub,dim,fobj]=Get_Functions_details('F3');
 
     load test.txt
    x=test;
    %I2=x(1:150,1:4);
    I2(:,1)=x(1:80,2);
    I2(:,2)=x(1:80,3);
    I2(:,3)=x(1:80,4);
    I2(:,4)=x(1:80,5);
    I2(:,5)=x(1:80,6);
    I2(:,6)=x(1:80,7);
    I2(:,7)=x(1:80,8);
    I2(:,8)=x(1:80,9);
    I2(:,9)=x(1:80,10);
    I2(:,10)=x(1:80,11);
    I2(:,11)=x(1:80,12);
    I2(:,12)=x(1:80,13);
    I2(:,13)=x(1:80,14);
    I2(:,14)=x(1:80,15);
    I2(:,15)=x(1:80,16);
    I2(:,16)=x(1:80,17);
    I2(:,17)=x(1:80,18);
    I2(:,18)=x(1:80,19);
    I2(:,19)=x(1:80,20);
    I2(:,20)=x(1:80,21);
    I2(:,21)=x(1:80,22);
    I2(:,22)=x(1:80,23);
    T=x(1:80,1);
    %I=(I2-0.1)./(7.9-0.1)
    
    Hno=45;
    dim = 24*45+1
    
    
        [Best_score,Best_pos]=DA(sahep,ithep,lb,ub,dim,fobj,curve,app);
        Rrate=0;
        W=Best_pos(1:23*Hno);
        B=Best_pos(23*Hno+1:dim);
        for pp=1:80
            actualvalue=my_simulate(22,Hno,1,W,B,I2(pp,:) );
            if(T(pp)==1)
                if (actualvalue>=0.95)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)==0)
                if (actualvalue(1)<0.05)
                    Rrate=Rrate+1;
                end
            end
            app.AccuracyEditField.Value=strcat( num2str((Rrate/80)*100),'%');
            pause(0.02);
        end
        
        
         Final_rate=(Rrate/80)*100;
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);


end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Hepatitis_GWO%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.HepatitisButton.Value==1 && app.GWOButton.Value==1
 [lb,ub,dim,fobj]=Get_Functions_details('F3');
 
     load test.txt
    x=test;
    %I2=x(1:150,1:4);
    I2(:,1)=x(1:80,2);
    I2(:,2)=x(1:80,3);
    I2(:,3)=x(1:80,4);
    I2(:,4)=x(1:80,5);
    I2(:,5)=x(1:80,6);
    I2(:,6)=x(1:80,7);
    I2(:,7)=x(1:80,8);
    I2(:,8)=x(1:80,9);
    I2(:,9)=x(1:80,10);
    I2(:,10)=x(1:80,11);
    I2(:,11)=x(1:80,12);
    I2(:,12)=x(1:80,13);
    I2(:,13)=x(1:80,14);
    I2(:,14)=x(1:80,15);
    I2(:,15)=x(1:80,16);
    I2(:,16)=x(1:80,17);
    I2(:,17)=x(1:80,18);
    I2(:,18)=x(1:80,19);
    I2(:,19)=x(1:80,20);
    I2(:,20)=x(1:80,21);
    I2(:,21)=x(1:80,22);
    I2(:,22)=x(1:80,23);
    T=x(1:80,1);
    %I=(I2-0.1)./(7.9-0.1)
    
    Hno=45;
    dim = 24*45+1
    
    
        [Best_score,Best_pos]=GWO(sahep,ithep,lb,ub,dim,fobj,curve,app);
        Rrate=0;
        W=Best_pos(1:23*Hno);
        B=Best_pos(23*Hno+1:dim);
        for pp=1:80
            actualvalue=my_simulate(22,Hno,1,W,B,I2(pp,:) );
            if(T(pp)==1)
                if (actualvalue>=0.95)
                    Rrate=Rrate+1;
                end
            end
            if(T(pp)==0)
                if (actualvalue(1)<0.05)
                    Rrate=Rrate+1;
                end
            end
            app.AccuracyEditField.Value=strcat( num2str((Rrate/80)*100),'%');
            pause(0.02);
            
        end
        
        
         Final_rate=(Rrate/80)*100;
        app.AccuracyEditField.Value=strcat( num2str(Final_rate),'%');
        app.MSEEditField.Value=num2str(Best_score);   
end

app.TrainingtimeEditField.Value=strcat( num2str(toc),' S');
        end

        % Button pushed function: clearplotButton
        function clearplotButtonPushed(app, event)
                        cla(app.UIAxes);
        end

        % Button pushed function: GotopredcitCOVID19appButton
        function GotopredcitCOVID19appButtonPushed(app, event)
            app3;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainOptimizeMLPwithDAGWOUIFigure = uifigure('Visible', 'off');
            app.TrainOptimizeMLPwithDAGWOUIFigure.Name = 'Train/Optimize MLP with DA/GWO ';
            app.Image = uiimage(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.Image.ImageSource = 'bg3.png';
            app.UIAxes = uiaxes(app.TrainOptimizeMLPwithDAGWOUIFigure);
            title(app.UIAxes, 'Convergence curve')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Best score obtaind')
            app.GotopredcitCOVID19appButton = uibutton(app.TrainOptimizeMLPwithDAGWOUIFigure, 'push');
            app.GotopredcitCOVID19appButton.ButtonPushedFcn = createCallbackFcn(app, @GotopredcitCOVID19appButtonPushed, true);
            app.GotopredcitCOVID19appButton.Text = 'Go to predcit COVID-19 app';
            app.EXITButton = uibutton(app.TrainOptimizeMLPwithDAGWOUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.ChoosedatasetButtonGroup = uibuttongroup(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.ChoosedatasetButtonGroup.Title = 'Choose dataset';
            app.BreastCancerButton = uiradiobutton(app.ChoosedatasetButtonGroup);
            app.BreastCancerButton.Text = 'Breast Cancer';
            app.BreastCancerButton.Value = true;
            app.HepatitisButton = uiradiobutton(app.ChoosedatasetButtonGroup);
            app.HepatitisButton.Text = 'Hepatitis';
            app.HeartDiseaseButton = uiradiobutton(app.ChoosedatasetButtonGroup);
            app.HeartDiseaseButton.Text = 'Heart Disease';
            app.OptimizerTrainerButtonGroup = uibuttongroup(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.OptimizerTrainerButtonGroup.Title = 'Optimizer/Trainer';
            app.DAButton = uiradiobutton(app.OptimizerTrainerButtonGroup);
            app.DAButton.Text = 'DA';
            app.DAButton.Value = true;
            app.GWOButton = uiradiobutton(app.OptimizerTrainerButtonGroup);
            app.GWOButton.Text = 'GWO';
            app.TrainButton = uibutton(app.TrainOptimizeMLPwithDAGWOUIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.clearplotButton = uibutton(app.TrainOptimizeMLPwithDAGWOUIFigure, 'push');
            app.clearplotButton.ButtonPushedFcn = createCallbackFcn(app, @clearplotButtonPushed, true);
            app.clearplotButton.Text = 'clear plot';
            app.TextArea = uitextarea(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.MSEEditFieldLabel = uilabel(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.MSEEditFieldLabel.Text = 'MSE:';
            app.MSEEditField = uieditfield(app.TrainOptimizeMLPwithDAGWOUIFigure, 'text');
            app.AccuracyEditFieldLabel = uilabel(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy:';
            app.AccuracyEditField = uieditfield(app.TrainOptimizeMLPwithDAGWOUIFigure, 'text');
            app.TrainingtimeEditFieldLabel = uilabel(app.TrainOptimizeMLPwithDAGWOUIFigure);
            app.TrainingtimeEditFieldLabel.Text = 'Training time:';
            app.TrainingtimeEditField = uieditfield(app.TrainOptimizeMLPwithDAGWOUIFigure, 'text');
            % Show the figure after all components are created
            app.TrainOptimizeMLPwithDAGWOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrainOptimizeMLPwithDAGWOUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainOptimizeMLPwithDAGWOUIFigure)
        end
    end
end