classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CNNDAforCOVID19PredictionUIFigure  matlab.ui.Figure
        Image                      matlab.ui.control.Image
        BrowseforXRayimageButton   matlab.ui.control.Button
        PredictCOVID19Button       matlab.ui.control.Button
        GOtoMLPDAGWOappButton      matlab.ui.control.Button
        EXITButton                 matlab.ui.control.Button
        ShowCNNArchitectureButton  matlab.ui.control.Button
        ClickonBrowseButtonPanel   matlab.ui.container.Panel
        Image2                     matlab.ui.control.Image
    end

    
    properties (Access = public)
        img;
        netName;
        file;
        path;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.CNNDAforCOVID19PredictionUIFigure.Visible = 'off'; % hide figure while Icon is still old icon
drawnow %It seems we need to pause execution here otherwise cefWindowList won't have the UIFigure in it yet
% Find the CEF window for the running app's figure: this idea
% comes from DesktopFigureService MALTAB internal class (courtesy)
cefWindowList = matlab.internal.webwindowmanager.instance.windowList;
url = matlab.ui.internal.FigureServices.getFigureURL(app.CNNDAforCOVID19PredictionUIFigure);
[idx] = arrayfun(@(ww)strcmp(url,ww.URL), cefWindowList);
hWin = cefWindowList(idx);

    hWin.Icon = which('virus.ico');

app.CNNDAforCOVID19PredictionUIFigure.Visible = 'on';
        end

        % Button pushed function: GOtoMLPDAGWOappButton
        function GOtoMLPDAGWOappButtonPushed(app, event)
            app2;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: BrowseforXRayimageButton
        function BrowseforXRayimageButtonPushed(app, event)
            [app.file,app.path]=uigetfile({'*.jpeg';'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.Image2.ImageSource=fullfile(app.path,app.file);
            app.img=fullfile(app.path,app.file);
        end

        % Button pushed function: PredictCOVID19Button
        function PredictCOVID19ButtonPushed(app, event)
load('ResNet50_1_among_10_folds.mat');

netName = "netTransfer";
net = eval(netName);

inputSize = net.Layers(1).InputSize(1:2);
classes = net.Layers(end).Classes;
layerName = activationLayerName(netName);

    im = imread(app.img);
    
   % Some images might be RGB, convert them to Grayscale 
    if ~ismatrix(im)
    im=rgb2gray(im); 
    end

% Replicate the image 3 times to create an RGB image
Iout = cat(3,im,im,im);
    
    
    imResized = imresize(Iout,[inputSize(1), NaN]);
    imageActivations = activations(net,imResized,layerName);
    
     scores = squeeze(mean(imageActivations,[1 2]));
    
  
        fcWeights = net.Layers(end-2).Weights;
        fcBias = net.Layers(end-2).Bias;
        scores =  fcWeights*scores + fcBias;
        
        [~,classIds] = maxk(scores,1);
        
    weightVector = shiftdim(fcWeights(classIds(1),:),-1);
    classActivationMap = sum(imageActivations.*weightVector,3);
   
    
    scores = exp(scores)/sum(exp(scores));     
    maxScores = scores(classIds);
    labels = classes(classIds);
    
    subplot(1,2,1)
    
    imshow(Iout)
    title(string(labels) + ", " + string(maxScores));
    
    subplot(1,2,2)
    CAMshow(Iout,classActivationMap)
    title(string(labels) + ", " + string(maxScores));
    
    set(gcf,'NumberTitle','off') %don't show the figure number
    set(gcf,'Name','Prediction Results') %select the name you want
    drawnow
     

        end

        % Button pushed function: ShowCNNArchitectureButton
        function ShowCNNArchitectureButtonPushed(app, event)
load('ResNet50_1_among_10_folds.mat');
analyzeNetwork(netTransfer);
          

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CNNDAforCOVID19PredictionUIFigure = uifigure('Visible', 'off');
            app.CNNDAforCOVID19PredictionUIFigure.Name = 'CNN-DA for COVID-19 Prediction';
            app.Image = uiimage(app.CNNDAforCOVID19PredictionUIFigure);
            app.Image.ImageSource = 'bg3.png';
            app.BrowseforXRayimageButton = uibutton(app.CNNDAforCOVID19PredictionUIFigure, 'push');
            app.BrowseforXRayimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforXRayimageButtonPushed, true);
            app.BrowseforXRayimageButton.Tooltip = {'Browse for an X-Ray image  for COVID-19 Prediction'};
            app.BrowseforXRayimageButton.Text = 'Browse for X-Ray image';
            app.PredictCOVID19Button = uibutton(app.CNNDAforCOVID19PredictionUIFigure, 'push');
            app.PredictCOVID19Button.ButtonPushedFcn = createCallbackFcn(app, @PredictCOVID19ButtonPushed, true);
            app.PredictCOVID19Button.Tooltip = {'Predict  COVID-19 infection possibility'};
            app.PredictCOVID19Button.Text = 'Predict COVID-19';
            app.GOtoMLPDAGWOappButton = uibutton(app.CNNDAforCOVID19PredictionUIFigure, 'push');
            app.GOtoMLPDAGWOappButton.ButtonPushedFcn = createCallbackFcn(app, @GOtoMLPDAGWOappButtonPushed, true);
            app.GOtoMLPDAGWOappButton.Text = 'GO to MLP-DA/GWO app';
            app.EXITButton = uibutton(app.CNNDAforCOVID19PredictionUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.ShowCNNArchitectureButton = uibutton(app.CNNDAforCOVID19PredictionUIFigure, 'push');
            app.ShowCNNArchitectureButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCNNArchitectureButtonPushed, true);
            app.ShowCNNArchitectureButton.Text = 'Show CNN Architecture';
            app.ClickonBrowseButtonPanel = uipanel(app.CNNDAforCOVID19PredictionUIFigure);
            app.ClickonBrowseButtonPanel.Tooltip = {'Click  on browse for an X-Ray image  '};
            app.ClickonBrowseButtonPanel.Title = 'Click on Browse Button';
            app.Image2 = uiimage(app.ClickonBrowseButtonPanel);
            app.Image2.ImageSource = 'folder_blue_explorer.png';
            % Show the figure after all components are created
            app.CNNDAforCOVID19PredictionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CNNDAforCOVID19PredictionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CNNDAforCOVID19PredictionUIFigure)
        end
    end
end